classdef Code_21090174 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BuildSphereButton               matlab.ui.control.Button
        SphereLabel                     matlab.ui.control.Label
        SphereHideButton                matlab.ui.control.Button
        SphereRevealButton              matlab.ui.control.Button
        DisableAnimationButton          matlab.ui.control.Button
        Circle3Point3Button             matlab.ui.control.Button
        Circle3Point2Button             matlab.ui.control.Button
        Circle3Point1Button             matlab.ui.control.Button
        Circle2CentreButton             matlab.ui.control.Button
        Circle1PointButton              matlab.ui.control.Button
        Circle1CentreButton             matlab.ui.control.Button
        ModifyElementsButton            matlab.ui.control.Button
        LoadCoordinatesButton           matlab.ui.control.Button
        CalculatedclosestpointsRevealButton  matlab.ui.control.Button
        CalculatedclosestpointsHideButton  matlab.ui.control.Button
        CalculatedclosestpointsLabel    matlab.ui.control.Label
        InitialuserselectedpointsRevealButton  matlab.ui.control.Button
        InitialuserselectedpointsHideButton  matlab.ui.control.Button
        InitialuserselectedpointsLabel  matlab.ui.control.Label
        PerpendicularbisectorsRevealButton  matlab.ui.control.Button
        PerpendicularbisectorsHideButton  matlab.ui.control.Button
        PerpendicularbisectorsLabel     matlab.ui.control.Label
        Circle3RevealButton             matlab.ui.control.Button
        Circle3Label                    matlab.ui.control.Label
        Circle3HideButton               matlab.ui.control.Button
        Circle2RevealButton             matlab.ui.control.Button
        Circle2HideButton               matlab.ui.control.Button
        Circle2Label                    matlab.ui.control.Label
        ExportCoordinatesButton         matlab.ui.control.Button
        Circle1RevealButton             matlab.ui.control.Button
        Circle1HideButton               matlab.ui.control.Button
        Circle1Label                    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        RunConstructionButton           matlab.ui.control.Button
        EditFieldY                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        EditFieldX                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        EnterPointButton                matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        status;       
        c1;
        c2;
        c3;        
        bs;
        f;
        lim;
        upts;
        anim = 1;
        sec = 2;
        crez = 720;
        lrez = 0.01;
        mk = ['+' '*' 'o']; % centre, points, intersections
        cl = ['m' 'g' 'b' 'r']; % c1, c2, c3, intermediate constructions
    end
    
    methods (Access = private)

        function limits(app, d)
            app.lim(1,1) = min([app.lim(1,1); d(:,1)]);
            app.lim(1,2) = max([app.lim(1,2); d(:,1)]);
            app.lim(2,1) = min([app.lim(2,1); d(:,2)]);
            app.lim(2,2) = max([app.lim(2,2); d(:,2)]);
        end

        function d = circle(app, c, r)            
            n = app.crez;
            for i = 1 : n+1
                angle = ( 2.0 * pi * ( i - 1 ) ) / n;
                d(i,1) = c(1) + r * cos ( angle );
                d(i,2) = c(2) + r * sin ( angle );
            end  
            limits(app, d);                 
        end    

        function construct(app, data, colour, marker, fig)
            if (app.status ~= 62) && (app.status ~= 82)
                app.Label.Visible = 'off';
            end
            app.XEditFieldLabel.Visible = 'off';
            app.EditFieldX.Visible = 'off';
            app.YEditFieldLabel.Visible = 'off';
            app.EditFieldY.Visible = 'off';
            app.EnterPointButton.Visible = 'off';

            sz = size(data,1);              
            dlr = ceil( sz / app.crez+1 ) ^ 3;           
            
            if isnan(marker)
                app.UIAxes.XLim = [app.lim(1,1)-1 app.lim(1,2)+1];
                app.UIAxes.YLim = [app.lim(2,1)-1 app.lim(2,2)+1];
                i = 1;
                while i <= sz
                    if app.anim == 1
                        plot(app.UIAxes,data(1:i,1),data(1:i,2),'color',colour,'parent',fig); 
                        pause(0.1);                         
                    else
                        plot(app.UIAxes,data(:,1),data(:,2),'color',colour,'parent',fig);
                        break
                    end
                    i = i + dlr;
                end

                if i-dlr < sz
                    plot(app.UIAxes,data(:,1),data(:,2),'color',colour,'parent',fig);
                end
                
            else
                for i = 1 : 10  
                    if app.anim == 1
                        plot(app.UIAxes,data(1,1),data(1,2),'color',colour,'marker',marker,'MarkerSize',i,'parent',fig); 
                        pause(0.1);
                    else
                        plot(app.UIAxes,data(1,1),data(1,2),'color',colour,'marker',marker,'MarkerSize',i,'parent',fig);                        
                    end
                end
            end

            app.Label.Visible = 'on';
            if (app.status ~= 62) && (app.status ~= 70) && (app.status ~= 82)
                if (app.status ~= 43) && (app.status ~= 51) && (app.status ~= 82)
                    app.XEditFieldLabel.Visible = 'on';
                    app.EditFieldX.Visible = 'on';
                    app.YEditFieldLabel.Visible = 'on';
                    app.EditFieldY.Visible = 'on';
                end
                app.EnterPointButton.Visible = 'on';
            end
        end
        
        function [m, b, d] = ptsToBis(app, p1, p2)
            ax = p1(1);
            ay = p1(2);
            bx = p2(1);
            by = p2(2);
            d = [];

            if ay == by
                mid = (ax+bx)/2;
                m = mid;
                b = nan;
                for i = app.lim(2,1) : app.lrez : app.lim(2,2)
                    d = [d; [mid, i]];
                end
            elseif ax == bx
                mid = (ay+by)/2;
                m = nan;
                b = mid;
                for i = app.lim(1,1) : app.lrez : app.lim(1,2)
                    d = [d; [i, mid]];
                end            
            else
                midx = (ax+bx)/2;
                midy = (ay+by)/2;
                m = -1 * (1/((by-ay)/(bx-ax)));
                b = midy - m * midx;                
                for i = app.lim(1,1) : app.lrez : app.lim(1,2)
                    d = [d; [i m*i+b]];
                end
            end
        end        

        function pInt = ptIntLine(~, p, l)
            px = p(1);
            py = p(2);
            m = l(1);
            b = l(2);

            if isnan(m)
                pInt = [px, b];                
            elseif isnan(b)
                pInt = [m, py];                
            else                
                m2 = -1 * 1/m;
                b2 = py - m2 * px;
                x = (b2 - b)/(m - m2);
                y = m * x + b;
                pInt = [x, y];
            end
        end

        function  int = lineIntLine(~, l1, l2)
            m1 = l1(1);
            b1 = l1(2);
            m2 = l2(1);
            b2 = l2(2);

            if sum(isnan([l1 l2])) == 0
                x = (b2-b1)/(m1-m2);
                y = m1 * x + b1;
                int = [x, y];
            elseif sum(isnan([l1 l2])) == 2
                if isnan(m1)
                    int = [m2, b1];                    
                else                    
                    int = [m1, b2];
                end
            elseif sum(isnan(l1)) == 0
                if isnan(m2)
                    int = [(b2-b1)/m1, b2];
                else
                    int = [m2, m1*m2+b1];
                end
            elseif sum(isnan(l2)) == 0
                if isnan(m1)
                    int = [(b1-b2)/m2, b1];
                else
                    int = [m1, m2*m1+b2];
                end
            end
        end        

        function [p1, p2] = cIntC(~, c1, r1, c2, r2)
            c1x = c1(1);
            c1y = c1(2);
            c2x = c2(1);
            c2y = c2(2);

            if c1y == c2y
                a = r1;
                b = r2;
                c = norm(c1 - c2);
                p = (a+b+c)/2;
                s = sqrt(p*(p-a)*(p-b)*(p-c));
                h = 2*s/c;
                if c1x < c2x
                    cxmin = c1x;
                    rmin = r1;
                else
                    cxmin = c2x;
                    rmin = r2;
                end
                d = sqrt(rmin^2 - h^2);
                x = cxmin + d;
                y1 = c1y - h;
                y2 = c1y + h;
                p1 = [x y1];
                p2 = [x y2];

            elseif c1x == c2x
                a = r1;
                b = r2;
                c = norm(c1 - c2);
                p = (a+b+c)/2;
                s = sqrt(p*(p-a)*(p-b)*(p-c));
                h = 2*s/c;
                if c1y < c2y
                    cymin = c1y;
                    rmin = r1;
                else
                    cymin = c2y;
                    rmin = r2;
                end
                d = sqrt(rmin^2 - h^2);
                x1 = c1x - h;
                x2 = c1x + h;
                y = cymin + d;                
                p1 = [x1 y];
                p2 = [x2 y];
                
            else
                cm = (2 * c1x - 2 * c2x) / (2 * c2y - 2 * c1y);  
                cb = -1 * ((c1x^2 + c1y^2 - r1^2) - (c2x^2 + c2y^2 - r2^2))/(2 * c2y - 2 * c1y);            
                a = cm^2 + 1;
                b = 2 * cm * (cb - c2y) - 2 * c2x;
                c = c2x^2 + (cb - c2y)^2 - r2^2;
                p = [a b c];
                x = roots(p);
                x1 = x(1);
                x2 = x(2);
                y1 = cm * x1 + cb;
                y2 = cm * x2 + cb;
                p1 = [x1 y1];
                p2 = [x2 y2];                
            end
        end

        function dt = transf(~, d, t)            
            dt = (t*[d ones(size(d,1),1)]')';
            dt = dt(:,1:2);
        end
        
        function rez = circle1(app)

            if app.status ~= 62

                if app.c1.c == app.c1.p                
                    rez = 'ERROR: The centre of the Circle 1 and the point on its circumference should be different.';                
                    return
                end
                
                app.lim(1,:) = [app.c1.c(1) app.c1.c(1)];
                app.lim(2,:) = [app.c1.c(2) app.c1.c(2)];    
                r = norm(app.c1.c - app.c1.p);
                app.c1.r = r;
                app.c1.d = circle(app, app.c1.c, app.c1.r);
            end            
            
            construct(app,app.c1.d,app.cl(1),nan,app.f.c1);
            
            app.Circle1Label.Visible = 'on';
            app.Circle1HideButton.Visible = 'on';
            app.Circle1RevealButton.Visible = 'on';
            
            construct(app,app.c1.c,app.cl(1),app.mk(1),app.f.ip); 
            
            app.InitialuserselectedpointsLabel.Visible = 'on';
            app.InitialuserselectedpointsHideButton.Visible = 'on';
            app.InitialuserselectedpointsRevealButton.Visible = 'on';  

            construct(app,app.c1.p,app.cl(1),app.mk(2),app.f.ip);                    

            rez = [];
        end
        
        function rez = circle2(app)

            if app.status ~= 62

                if app.c2.c == app.c1.c
                    rez = 'ERROR: Circle 1 and Circle 2 centres should be different points.';                
                    return
                end
    
                if norm(app.c2.c - app.c1.c) == app.c1.r
                    rez = 'ERROR: Circle 2 centre cannot lie on the Circle 1 circumference.';                
                    return
                end
                   
                r = abs(norm(app.c2.c - app.c1.c) - app.c1.r);
                app.c2.r = r;
                app.c2.d = circle(app, app.c2.c, app.c2.r); 
            end

            construct(app,app.c2.d,app.cl(2),nan,app.f.c2);   

            app.Circle2Label.Visible = 'on';
            app.Circle2HideButton.Visible = 'on';
            app.Circle2RevealButton.Visible = 'on'; 

            construct(app,app.c2.c,app.cl(2),app.mk(1),app.f.ip); 

            rez = [];    
        end
        
        function rez = circle3(app)

            if ((app.status == 42) || (app.status >= 70))

                d11 = abs(norm(app.c3.p1 - app.c1.c) - app.c1.r);
                d12 = abs(norm(app.c3.p1 - app.c2.c) - app.c2.r);                
                if d11 < d12
                    app.c3.p1c = 1;
                    app.c3.p1cp = [app.c1.c, app.c1.r];
                else
                    app.c3.p1c = 2;
                    app.c3.p1cp = [app.c2.c, app.c2.r];
                end
    
                d21 = abs(norm(app.c3.p2 - app.c1.c) - app.c1.r);
                d22 = abs(norm(app.c3.p2 - app.c2.c) - app.c2.r);                
                if d21 < d22
                    app.c3.p2c = 1;
                    app.c3.p2cp = [app.c1.c, app.c1.r];
                else
                    app.c3.p2c = 2;
                    app.c3.p2cp = [app.c2.c, app.c2.r];
                end
    
                if app.c3.p1c == app.c3.p2c
                    rez = 'ERROR: Both user points are closest to the same circle.';                
                    return
                end
    
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
                px = app.c3.p1(1,1);
                py = app.c3.p1(1,2);
                cx = app.c3.p1cp(1,1);
                cy = app.c3.p1cp(1,2);
                r = app.c3.p1cp(1,3);
                x1 = cx + r * (( px - cx )/(sqrt((px-cx)^2 + (py-cy)^2)));
                y1 = cy + r * (( py - cy )/(sqrt((px-cx)^2 + (py-cy)^2)));
                app.c3.p1cp = [x1, y1];                    
                                            
                px = app.c3.p2(1,1);
                py = app.c3.p2(1,2);
                cx = app.c3.p2cp(1,1);
                cy = app.c3.p2cp(1,2);
                r = app.c3.p2cp(1,3);
                x2 = cx + r * (( px - cx )/(sqrt((px-cx)^2 + (py-cy)^2)));
                y2 = cy + r * (( py - cy )/(sqrt((px-cx)^2 + (py-cy)^2)));
                app.c3.p2cp = [x2, y2];
    
                if app.c3.p1cp == app.c3.p2cp
                    rez = "ERROR: Both user points' circles projections are equal.";                
                    return
                end
    
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
                d11 = abs(norm(app.c3.p1cp - app.c1.c) - app.c1.r);
                d12 = abs(norm(app.c3.p1cp - app.c2.c) - app.c2.r);
                d21 = abs(norm(app.c3.p2cp - app.c1.c) - app.c1.r);
                d22 = abs(norm(app.c3.p2cp - app.c2.c) - app.c2.r);
                
                if ((d11 == d12) && (d21 == d22))
                    rez = "ERROR: Both user points' projections belong to the bisector.";                
                    return
                end                

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                [app.bs.c1c2.m, app.bs.c1c2.b, app.bs.c1c2.d]  = ptsToBis(app, app.c1.c, app.c2.c);                
                if app.status == 42         
                    construct(app,app.bs.c1c2.d,app.cl(4),nan,app.f.bs); 
                    app.PerpendicularbisectorsLabel.Visible = 'on';
                    app.PerpendicularbisectorsHideButton.Visible = 'on';
                    app.PerpendicularbisectorsRevealButton.Visible = 'on';
                end 

                rez = [];                               
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            

            if ((app.status == 43) || (app.status == 62) || (app.status >= 70))

                if app.status == 62
                    construct(app,app.bs.c1c2.d,app.cl(4),nan,app.f.bs);
                    app.PerpendicularbisectorsLabel.Visible = 'on';
                    app.PerpendicularbisectorsHideButton.Visible = 'on';
                    app.PerpendicularbisectorsRevealButton.Visible = 'on';
                end

                if app.status ~= 62
            
                    app.c3.p3bp = ptIntLine(app, app.c3.p3, [app.bs.c1c2.m, app.bs.c1c2.b]);
                    limits(app, app.c3.p3bp);
                    [app.bs.c1c2.m, app.bs.c1c2.b, app.bs.c1c2.d]  = ptsToBis(app, app.c1.c, app.c2.c);
                    construct(app,app.bs.c1c2.d,app.cl(4),nan,app.f.bs);
                    app.PerpendicularbisectorsLabel.Visible = 'on';
                    app.PerpendicularbisectorsHideButton.Visible = 'on';
                    app.PerpendicularbisectorsRevealButton.Visible = 'on';
        
                    [app.bs.p1p2.m, app.bs.p1p2.b, app.bs.p1p2.d]  = ptsToBis(app, app.c3.p1cp, app.c3.p2cp); 
                    [app.bs.p1p3.m, app.bs.p1p3.b, app.bs.p1p3.d]  = ptsToBis(app, app.c3.p1cp, app.c3.p3bp); 
                    app.c3.c = lineIntLine(app, [app.bs.p1p2.m, app.bs.p1p2.b], [app.bs.p1p3.m, app.bs.p1p3.b]);
                    limits(app, app.c3.c);
                    [app.bs.p1p2.m, app.bs.p1p2.b, app.bs.p1p2.d]  = ptsToBis(app, app.c3.p1cp, app.c3.p2cp); 
                    [app.bs.p1p3.m, app.bs.p1p3.b, app.bs.p1p3.d]  = ptsToBis(app, app.c3.p1cp, app.c3.p3bp);                     
                        
                    app.c3.r = norm(app.c3.c - app.c3.p3bp);
                    app.c3.d = circle(app, app.c3.c, app.c3.r);
                end
                
                construct(app,app.bs.p1p2.d,app.cl(4),nan,app.f.bs);
                construct(app,app.bs.p1p3.d,app.cl(4),nan,app.f.bs);                

                construct(app,app.c3.c,app.cl(3),app.mk(1),app.f.c3);  

                construct(app,app.c3.p1,app.cl(4),app.mk(2),app.f.ip); 
                construct(app,app.c3.p2,app.cl(4),app.mk(2),app.f.ip); 
                construct(app,app.c3.p3,app.cl(4),app.mk(2),app.f.ip); 

                construct(app,app.c3.p1cp,app.cl(3),app.mk(2),app.f.cp); 
                construct(app,app.c3.p2cp,app.cl(3),app.mk(2),app.f.cp); 
                construct(app,app.c3.p3bp,app.cl(3),app.mk(2),app.f.cp); 

                app.CalculatedclosestpointsLabel.Visible = 'on';
                app.CalculatedclosestpointsHideButton.Visible = 'on';
                app.CalculatedclosestpointsRevealButton.Visible = 'on';

                construct(app,app.c3.d,app.cl(3),nan,app.f.c3);
                
                app.Circle3Label.Visible = 'on';
                app.Circle3HideButton.Visible = 'on';
                app.Circle3RevealButton.Visible = 'on';
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if ((app.status == 51) || (app.status == 62) || (app.status >= 70))

                if app.status ~= 62
    
                    [p1, p2] = cIntC(app, app.c3.c, app.c3.r, app.c1.c, app.c1.r);
                    app.c3.i1 = [p1; p2];
                    
                    [p1, p2] = cIntC(app, app.c3.c, app.c3.r, app.c2.c, app.c2.r);
                    app.c3.i2 = [p1; p2];
                end            

                construct(app,app.c3.i1(1,:),app.cl(1),app.mk(3),app.f.c3); 
                construct(app,app.c3.i1(2,:),app.cl(1),app.mk(3),app.f.c3); 
                construct(app,app.c3.i2(1,:),app.cl(2),app.mk(3),app.f.c3); 
                construct(app,app.c3.i2(2,:),app.cl(2),app.mk(3),app.f.c3);                  
            end
        end     
        
        function  reset(app)

            if app.status == 21
                app.upts = [];
                grid(app.UIAxes,'off')
                app.RunConstructionButton.Text = 'Restart Construction';
                app.Label.Text = 'Enter the centre of the first circle.';
                app.Label.Visible = 'on';                  
                app.XEditFieldLabel.Visible = 'on';
                app.EditFieldX.Value = 0;                
                app.EditFieldX.Visible = 'on';
                app.YEditFieldLabel.Visible = 'on';
                app.EditFieldY.Value = 0;                
                app.EditFieldY.Visible = 'on';
                app.EnterPointButton.Text = 'Enter Point';
                app.EnterPointButton.Visible = 'on';                

            elseif app.status == 70
                grid(app.UIAxes,'off')
                app.RunConstructionButton.Text = 'Run Construction';
                app.Label.Visible = 'off';  
                app.XEditFieldLabel.Visible = 'off';
                app.EditFieldX.Visible = 'off';
                app.YEditFieldLabel.Visible = 'off';
                app.EditFieldY.Visible = 'off';
                app.EnterPointButton.Visible = 'off';  

            elseif app.status == 81
                app.RunConstructionButton.Text = 'Run Construction';
                app.Label.Text = 'Enter new coordinates and choose which element would you like to modify.';   
                app.XEditFieldLabel.Visible = 'on';
                app.EditFieldX.Value = 0;
                app.EditFieldX.Visible = 'on';
                app.YEditFieldLabel.Visible = 'on';
                app.EditFieldY.Value = 0;
                app.EditFieldY.Visible = 'on';
                app.EnterPointButton.Visible = 'off';                              
                app.Circle1CentreButton.Visible = 'on';
                app.Circle1PointButton.Visible = 'on';
                app.Circle2CentreButton.Visible = 'on';
                app.Circle3Point1Button.Visible = 'on';
                app.Circle3Point2Button.Visible = 'on';
                app.Circle3Point3Button.Visible = 'on';

            elseif app.status == 82
                grid(app.UIAxes,'off')
                app.Label.Text = 'Modification in progress.';
                app.XEditFieldLabel.Visible = 'off';
                app.EditFieldX.Visible = 'off';
                app.YEditFieldLabel.Visible = 'off';
                app.EditFieldY.Visible = 'off';                               
            end    
    
            if app.status ~= 81

                cla(app.UIAxes);
                app.f.c1 = hggroup('parent', app.UIAxes);
                app.f.c2 = hggroup('parent', app.UIAxes);
                app.f.c3 = hggroup('parent', app.UIAxes);
                app.f.bs = hggroup('parent', app.UIAxes);
                app.f.ip = hggroup('parent', app.UIAxes);
                app.f.cp = hggroup('parent', app.UIAxes);
                if app.status ~= 62
                    app.f.sf = [];
                end

                app.Circle1CentreButton.Visible = 'off';
                app.Circle1PointButton.Visible = 'off';
                app.Circle2CentreButton.Visible = 'off';
                app.Circle3Point1Button.Visible = 'off';
                app.Circle3Point2Button.Visible = 'off';
                app.Circle3Point3Button.Visible = 'off';            

                app.InitialuserselectedpointsLabel.Visible = 'off';
                app.InitialuserselectedpointsHideButton.Visible = 'off';
                app.InitialuserselectedpointsRevealButton.Visible = 'off';
                
                app.CalculatedclosestpointsLabel.Visible = 'off';
                app.CalculatedclosestpointsHideButton.Visible = 'off';
                app.CalculatedclosestpointsRevealButton.Visible = 'off';  
                
                app.PerpendicularbisectorsLabel.Visible = 'off';
                app.PerpendicularbisectorsHideButton.Visible = 'off';
                app.PerpendicularbisectorsRevealButton.Visible = 'off';   
                
                app.Circle1Label.Visible = 'off';
                app.Circle1HideButton.Visible = 'off';
                app.Circle1RevealButton.Visible = 'off';
                
                app.Circle2Label.Visible = 'off';
                app.Circle2HideButton.Visible = 'off';
                app.Circle2RevealButton.Visible = 'off';
                
                app.Circle3Label.Visible = 'off';
                app.Circle3HideButton.Visible = 'off';
                app.Circle3RevealButton.Visible = 'off';  
    
                app.SphereLabel.Visible = 'off';
                app.SphereHideButton.Visible = 'off';
                app.SphereRevealButton.Visible = 'off';   
            end
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EnterPointButton
        function EnterPointButtonPushed(app, event)
            
            if app.status == 61

                app.status = 62;

                reset(app);
                app.Label.Text = 'Geometric transformation in progress.';
                app.EnterPointButton.Visible = 'off';

                dx = -1 * app.c3.c(1,1);
                dy = -1 * app.c3.c(1,2);
                tr = [1 0 dx; 0 1 dy; 0 0 1];
                disp('Translation matrix: ');
                disp(tr);
    
                if isnan(app.bs.c1c2.m)
                    a = 0;
                elseif isnan(app.bs.c1c2.b)
                    a = deg2rad(90);
                else
                    a = -1 * atan(app.bs.c1c2.m);
                end
                
                rot = [cos(a), -sin(a), 0; sin(a), cos(a), 0; 0, 0, 1];
                disp('Rotation matrix: ');
                disp(rot);

                t = rot * tr;
                disp('Overall combined transformation: ');
                disp(t);                
                
                app.c1.d = transf(app, app.c1.d, t);
                app.c1.c = transf(app, app.c1.c, t);
                app.c1.p = transf(app, app.c1.p, t);                                
                
                app.c2.d = transf(app, app.c2.d, t);
                app.c2.c = transf(app, app.c2.c, t);                
                
                app.c3.d = transf(app, app.c3.d, t);
                app.c3.c = transf(app, app.c3.c, t);
                app.c3.p1 = transf(app, app.c3.p1, t);
                app.c3.p2 = transf(app, app.c3.p2, t);
                app.c3.p3 = transf(app, app.c3.p3, t);
                app.c3.p1cp = transf(app, app.c3.p1cp, t);
                app.c3.p2cp = transf(app, app.c3.p2cp, t);
                app.c3.p3bp = transf(app, app.c3.p3bp, t);
                
                app.bs.c1c2.d = transf(app, app.bs.c1c2.d, t);
                app.bs.p1p2.d = transf(app, app.bs.p1p2.d, t);
                app.bs.p1p3.d = transf(app, app.bs.p1p3.d, t);
                
                app.c3.i1 = transf(app, app.c3.i1, t);
                app.c3.i2 = transf(app, app.c3.i2, t);

                app.lim(1,:) = [app.c1.c(1) app.c1.c(1)];
                app.lim(2,:) = [app.c1.c(2) app.c1.c(2)];
                limits(app,[app.c1.d; app.c2.d; app.c3.d]);
                
                circle1(app);
                circle2(app);
                circle3(app);

                if ~isempty(app.f.sf)
                    BuildSphereButtonPushed(app, event)
                end
                
                app.RunConstructionButton.Text = 'Run Construction';
                app.Label.Text = 'Geometric transformation and Construction sequence completed.';

                app.status = 63;
            end

            if app.status == 51
                circle3(app);
                app.Label.Text = 'Geometric transformation.';
                app.EnterPointButton.Text = 'Run Task 6';
                app.status = 61;
            end

            if app.status == 43
                x = app.EditFieldX.Value;
                y = app.EditFieldY.Value;
                app.EditFieldX.Value = 0;
                app.EditFieldY.Value = 0;
                app.c3.p3 = [x y];
                app.upts = [app.upts; [x y]];
                circle3(app);
                app.Label.Text = 'Find intersections of the third circle with the first two circles.';               
                app.EnterPointButton.Text = 'Run Task 5';
                app.status = 51;
            end

            if app.status == 42
                x = app.EditFieldX.Value;
                y = app.EditFieldY.Value;
                app.EditFieldX.Value = 0;
                app.EditFieldY.Value = 0;
                app.c3.p2 = [x y];
                er = circle3(app);
                if er               
                    app.Label.Text = strcat(er,' Please re-enter the first point for the third circle.');  
                    app.status = 41;
                    return
                end
                app.upts = [app.upts; app.c3.p1; app.c3.p2];
                app.Label.Text = 'Enter the third point for the third circle.';                
                app.status = 43;                 
            end

            if app.status == 41
                x = app.EditFieldX.Value;
                y = app.EditFieldY.Value;
                app.EditFieldX.Value = 0;
                app.EditFieldY.Value = 0;
                app.c3.p1 = [x y];
                app.Label.Text = 'Enter the second point for the third circle.';                
                app.status = 42;
            end
            
            if app.status == 31
                x = app.EditFieldX.Value;
                y = app.EditFieldY.Value;
                app.EditFieldX.Value = 0;
                app.EditFieldY.Value = 0; 
                app.c2.c = [x y];
                er = circle2(app);
                if er               
                    app.Label.Text = strcat(er,' Please re-enter Circle 2 centre.');                    
                    return
                end
                app.upts = [app.upts; [x y]];
                app.Label.Text = 'Enter the first point for the third circle.';                
                app.status = 41;                       
            end
                                 
            if app.status == 22
                x = app.EditFieldX.Value;
                y = app.EditFieldY.Value;
                app.EditFieldX.Value = 0;
                app.EditFieldY.Value = 0; 
                app.c1.p = [x y];
                er = circle1(app);
                if er               
                    app.Label.Text = strcat(er,' Please re-enter the point on circumference.');                    
                    return
                end
                app.upts = [app.upts; [x y]];
                app.Label.Text = 'Enter the centre of the second circle.';                
                app.status = 31;                          
            end
            
            if app.status == 21
                x = app.EditFieldX.Value;
                y = app.EditFieldY.Value;
                app.EditFieldX.Value = 0;
                app.EditFieldY.Value = 0;
                app.c1.c = [x y];
                app.upts = [x y];
                app.Label.Text = 'Enter the point, which will lie on the circumference of the first circle.';                                    
                app.status = 22;
            end
        end

        % Button pushed function: RunConstructionButton
        function RunConstructionButtonPushed(app, event)
            app.status = 21;
            reset(app);           
        end

        % Button pushed function: Circle1HideButton
        function Circle1HideButtonPushed(app, event)
            set(app.f.c1,'Visible','off')
        end

        % Button pushed function: Circle1RevealButton
        function Circle1RevealButtonPushed(app, event)
            set(app.f.c1,'Visible','on')
        end

        % Button pushed function: Circle2HideButton
        function Circle2HideButtonPushed(app, event)
            set(app.f.c2,'Visible','off')
        end

        % Button pushed function: Circle3HideButton
        function Circle3HideButtonPushed(app, event)
            set(app.f.c3,'Visible','off')
        end

        % Button pushed function: Circle2RevealButton
        function Circle2RevealButtonPushed(app, event)
            set(app.f.c2,'Visible','on')
        end

        % Button pushed function: Circle3RevealButton
        function Circle3RevealButtonPushed(app, event)
            set(app.f.c3,'Visible','on')
        end

        % Button pushed function: PerpendicularbisectorsHideButton
        function PerpendicularbisectorsHideButtonPushed(app, event)
            set(app.f.bs,'Visible','off')
        end

        % Button pushed function: PerpendicularbisectorsRevealButton
        function PerpendicularbisectorsRevealButtonPushed(app, event)
            set(app.f.bs,'Visible','on')
        end

        % Button pushed function: InitialuserselectedpointsHideButton
        function InitialuserselectedpointsHideButtonPushed(app, event)
            set(app.f.ip,'Visible','off')
        end

        % Button pushed function: InitialuserselectedpointsRevealButton
        function InitialuserselectedpointsRevealButtonPushed(app, event)
            set(app.f.ip,'Visible','on')
        end

        % Button pushed function: CalculatedclosestpointsHideButton
        function CalculatedclosestpointsHideButtonPushed(app, event)
            set(app.f.cp,'Visible','off')
        end

        % Button pushed function: CalculatedclosestpointsRevealButton
        function CalculatedclosestpointsRevealButtonPushed(app, event)
            set(app.f.cp,'Visible','on')
        end

        % Button pushed function: ExportCoordinatesButton
        function ExportCoordinatesButtonPushed(app, event)

            m1 = 'Points exported.';
            m2 = 'There is nothing to export.';
                        
            if ~isempty(app.upts)                
                expf = fopen('exported_coordinates.txt','w');
                for i = 1:size(app.upts,1)
                    fprintf(expf, '%.2f %.2f\n',app.upts(i,1), app.upts(i,2));
                end         
                fclose(expf);                 
                t0 = app.Label.Text;                                
                app.Label.Text = m1;
                
                if app.status == 70
                    app.Label.Visible = 'on';
                    pause(app.sec);
                    app.Label.Visible = 'off';
                else
                    pause(app.sec);                    
                end
                if app.status == 82
                    app.Label.Visible = 'on';
                    pause(app.sec);
                    app.Label.Visible = 'off';
                else
                    pause(app.sec);                    
                end
                app.Label.Text = t0;                                 
            else     
                if ~isempty(app.status)
                    t0 = app.Label.Text;                                
                    app.Label.Text = m2;                            
                    pause(app.sec);
                    app.Label.Text = t0; 
                else                                                   
                    app.Label.Text = m2;
                    app.Label.Visible = 'on';
                    pause(app.sec);
                    app.Label.Visible = 'off';                    
                end
            end
        end

        % Button pushed function: LoadCoordinatesButton
        function LoadCoordinatesButtonPushed(app, event)
         
            if app.status == 82
                reset(app);
                ppts = [app.c1.c; app.c1.p; app.c2.c; app.c3.p1; app.c3.p2; app.c3.p3];
                app.c1.c = app.upts(1,:);
                app.c1.p = app.upts(2,:);
                app.c2.c = app.upts(3,:);
                app.c3.p1 = app.upts(4,:);
                app.c3.p2 = app.upts(5,:);
                app.c3.p3 = app.upts(6,:);
            else
                app.status = 70; 
                reset(app);
                
                [fname, fpath] = uigetfile('*.txt');
                fl = strcat(fpath,fname);            
                impf = fopen(fl);                        
                
                if impf < 0
                    app.Label.Text = 'ERROR: The file has not been found.';
                    app.Label.Visible = 'on';
                    return
                end
    
                c = cell2mat(textscan(impf,'%f %f'));  
    
                if sum(sum(isnan(c))) > 0
                    app.Label.Text = 'ERROR: The input file has missing data.';                
                    app.Label.Visible = 'on';
                    return
                end
    
                if size(c,1) ~= 6               
                    app.Label.Text = 'ERROR: The file should contain 6 data points.';
                    app.Label.Visible = 'on';
                    return
                end
    
                app.c1.c = c(1,:);
                app.c1.p = c(2,:);
                app.c2.c = c(3,:);
                app.c3.p1 = c(4,:);
                app.c3.p2 = c(5,:);
                app.c3.p3 = c(6,:);
            end            

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            er = circle1(app);
            if er                
                app.Label.Text = er;
                app.Label.Visible = 'on';
                if app.status == 82
                    app.upts = ppts;
                end
                return
            end
            app.upts = [app.c1.c; app.c1.p];

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            er = circle2(app);
            if er              
                app.Label.Text = er;
                app.Label.Visible = 'on';
                if app.status == 82
                    app.upts = ppts;
                end
                return
            end
            app.upts = [app.upts; app.c2.c];

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            er = circle3(app);
            if er               
                app.Label.Text = er;
                app.Label.Visible = 'on';
                if app.status == 82
                    app.upts = ppts;
                end
                return
            end
            app.upts = [app.upts; app.c3.p1; app.c3.p2; app.c3.p3];

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if app.status == 70
                app.Label.Text = 'Construction from loaded points completed.';
                app.status = 71;
            elseif app.status == 82
                app.Label.Text = 'Modification completed.'; 
            end
            app.Label.Visible = 'on';            
        end

        % Button pushed function: ModifyElementsButton
        function ModifyElementsButtonPushed(app, event)
            if isempty(app.status)
                app.Label.Text = 'Nothing has been built yet.';
                app.Label.Visible = 'on';                
                return  
            elseif app.status == 21
                t0 = app.Label.Text;                                
                app.Label.Text = 'Nothing has been built yet.';                          
                pause(app.sec);
                app.Label.Text = t0;
                return
            elseif app.status < 51
                n = 6 - size(app.upts,1);                
                if n == 1
                    m = 'point';
                else
                    m = 'points';
                end
                n = num2str(n);                
                message = strcat('Please enter',{' '},n,' more ',{' '},m,', or Restart Construction.');
                t0 = app.Label.Text;                                
                app.Label.Text = message;                            
                pause(app.sec + 1);
                app.Label.Text = t0;  
                return
            elseif app.status == 62
                t0 = app.Label.Text;                                
                app.Label.Text = 'Please wait for the Geometric transformation to complete.';                          
                pause(app.sec + 1);
                app.Label.Text = t0;
                return
            elseif app.status == 63
                app.upts(1,:) = app.c1.c;
                app.upts(2,:) = app.c1.p;
                app.upts(3,:) = app.c2.c;
                app.upts(4,:) = app.c3.p1;
                app.upts(5,:) = app.c3.p2;
                app.upts(6,:) = app.c3.p3;
            elseif app.status == 70                
                t0 = app.Label.Text;                                
                app.Label.Text = 'Loading Construction in progress.'; 
                app.Label.Visible = 'on'; 
                pause(app.sec);
                app.Label.Visible = 'off'; 
                app.Label.Text = t0;  
                return
            elseif app.status == 82
                t0 = app.Label.Text;                                
                app.Label.Text = 'MODIFICATION IN PROGRESS';                          
                pause(app.sec + 1);
                app.Label.Text = t0;
                return
            end

            app.status = 81;
            reset(app);
        end

        % Button pushed function: Circle1CentreButton
        function Circle1CentreButtonPushed(app, event)
            x = app.EditFieldX.Value;
            y = app.EditFieldY.Value;
            app.upts(1,:) = [x y];           

            app.status = 82;            
            LoadCoordinatesButtonPushed(app,event);            
            app.status = 71;
        end

        % Button pushed function: Circle1PointButton
        function Circle1PointButtonPushed(app, event)
            x = app.EditFieldX.Value;
            y = app.EditFieldY.Value;
            app.upts(2,:) = [x y];

            app.status = 82;            
            LoadCoordinatesButtonPushed(app,event);            
            app.status = 71;
        end

        % Button pushed function: Circle2CentreButton
        function Circle2CentreButtonPushed(app, event)
            x = app.EditFieldX.Value;
            y = app.EditFieldY.Value;
            app.upts(3,:) = [x y];
            
            app.status = 82;            
            LoadCoordinatesButtonPushed(app,event);            
            app.status = 71;
        end

        % Button pushed function: Circle3Point1Button
        function Circle3Point1ButtonPushed(app, event)
            x = app.EditFieldX.Value;
            y = app.EditFieldY.Value;
            app.upts(4,:) = [x y];

            app.status = 82;            
            LoadCoordinatesButtonPushed(app,event);            
            app.status = 71;
        end

        % Button pushed function: Circle3Point2Button
        function Circle3Point2ButtonPushed(app, event)
            x = app.EditFieldX.Value;
            y = app.EditFieldY.Value;
            app.upts(5,:) = [x y];

            app.status = 82;            
            LoadCoordinatesButtonPushed(app,event);            
            app.status = 71;
        end

        % Button pushed function: Circle3Point3Button
        function Circle3Point3ButtonPushed(app, event)
            x = app.EditFieldX.Value;
            y = app.EditFieldY.Value;
            app.upts(6,:) = [x y];

            app.status = 82;            
            LoadCoordinatesButtonPushed(app,event);            
            app.status = 71;
        end

        % Button pushed function: DisableAnimationButton
        function DisableAnimationButtonPushed(app, event)
            if app.anim == 0
                app.anim = 1;
                app.DisableAnimationButton.Text = 'Disable Animation';
            else
                app.anim = 0;
                app.DisableAnimationButton.Text = 'Enable Animation';
            end
        end

        % Button pushed function: SphereHideButton
        function SphereHideButtonPushed(app, event)
            set(app.f.sf,'Visible','off')
        end

        % Button pushed function: SphereRevealButton
        function SphereRevealButtonPushed(app, event)
            set(app.f.sf,'Visible','on')
        end

        % Button pushed function: BuildSphereButton
        function BuildSphereButtonPushed(app, event)

            if isempty(app.status)
                app.Label.Text = 'Circle 3 has not been built yet.';
                app.Label.Visible = 'on';                
                return            
            elseif app.status < 50                
                t0 = app.Label.Text;                                
                app.Label.Text = 'Circle 3 has not been built yet.';                            
                pause(app.sec);
                app.Label.Text = t0;  
                return
            elseif (~isempty(app.f.sf)) && (app.status ~= 62)
                t0 = app.Label.Text;                                
                app.Label.Text = 'The sphere has been built already.';                            
                pause(app.sec);
                app.Label.Text = t0;
            elseif app.status == 70                
                t0 = app.Label.Text;                                
                app.Label.Text = 'Loading Construction in progress.'; 
                app.Label.Visible = 'on'; 
                pause(app.sec);
                app.Label.Visible = 'off'; 
                app.Label.Text = t0;  
                return
            else
                app.f.sf = hggroup('parent', app.UIAxes);
                grid(app.UIAxes,'on')
                [a,b,c] = sphere(50);
                x = app.c3.c(1);
                y = app.c3.c(2);
                r = app.c3.r; 
                i = 0;
                inc = 0.1;
                while i <= r                    
                    if app.anim == 1
                        surface(app.UIAxes, a*i+x, b*i+y, c*i,'EdgeColor','w','parent',app.f.sf); 
                        pause(0.1);                         
                    else
                        surface(app.UIAxes, a*r+x, b*r+y, c*r,'EdgeColor','w','parent',app.f.sf); 
                        break
                    end    
                    i = i + inc;
                end

                if i-inc < r                    
                    surface(app.UIAxes, a*r+x, b*r+y, c*r,'EdgeColor','w','parent',app.f.sf);
                end

                rotate3d(app.UIAxes,'on');
                app.SphereLabel.Visible = 'on';
                app.SphereHideButton.Visible = 'on';
                app.SphereRevealButton.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CM2104 - C21090174')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'padded';
            app.UIAxes.YLimitMethod = 'padded';
            app.UIAxes.ZLimitMethod = 'padded';
            app.EnterPointButton = uibutton(app.UIFigure, 'push');
            app.EnterPointButton.ButtonPushedFcn = createCallbackFcn(app, @EnterPointButtonPushed, true);
            app.EnterPointButton.Visible = 'off';
            app.EnterPointButton.Text = 'Enter Point';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Visible = 'off';
            app.XEditFieldLabel.Text = 'X';
            app.EditFieldX = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldX.Visible = 'off';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Visible = 'off';
            app.YEditFieldLabel.Text = 'Y';
            app.EditFieldY = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldY.Visible = 'off';
            app.RunConstructionButton = uibutton(app.UIFigure, 'push');
            app.RunConstructionButton.ButtonPushedFcn = createCallbackFcn(app, @RunConstructionButtonPushed, true);
            app.RunConstructionButton.Text = 'Run Construction';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Circle1Label = uilabel(app.UIFigure);
            app.Circle1Label.Visible = 'off';
            app.Circle1Label.Text = 'Circle 1:';
            app.Circle1HideButton = uibutton(app.UIFigure, 'push');
            app.Circle1HideButton.ButtonPushedFcn = createCallbackFcn(app, @Circle1HideButtonPushed, true);
            app.Circle1HideButton.Visible = 'off';
            app.Circle1HideButton.Text = 'Hide';
            app.Circle1RevealButton = uibutton(app.UIFigure, 'push');
            app.Circle1RevealButton.ButtonPushedFcn = createCallbackFcn(app, @Circle1RevealButtonPushed, true);
            app.Circle1RevealButton.Visible = 'off';
            app.Circle1RevealButton.Text = 'Reveal';
            app.ExportCoordinatesButton = uibutton(app.UIFigure, 'push');
            app.ExportCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCoordinatesButtonPushed, true);
            app.ExportCoordinatesButton.Text = 'Export Coordinates';
            app.Circle2Label = uilabel(app.UIFigure);
            app.Circle2Label.Visible = 'off';
            app.Circle2Label.Text = 'Circle 2:';
            app.Circle2HideButton = uibutton(app.UIFigure, 'push');
            app.Circle2HideButton.ButtonPushedFcn = createCallbackFcn(app, @Circle2HideButtonPushed, true);
            app.Circle2HideButton.Visible = 'off';
            app.Circle2HideButton.Text = 'Hide';
            app.Circle2RevealButton = uibutton(app.UIFigure, 'push');
            app.Circle2RevealButton.ButtonPushedFcn = createCallbackFcn(app, @Circle2RevealButtonPushed, true);
            app.Circle2RevealButton.Visible = 'off';
            app.Circle2RevealButton.Text = 'Reveal';
            app.Circle3HideButton = uibutton(app.UIFigure, 'push');
            app.Circle3HideButton.ButtonPushedFcn = createCallbackFcn(app, @Circle3HideButtonPushed, true);
            app.Circle3HideButton.Visible = 'off';
            app.Circle3HideButton.Text = 'Hide';
            app.Circle3Label = uilabel(app.UIFigure);
            app.Circle3Label.Visible = 'off';
            app.Circle3Label.Text = 'Circle 3:';
            app.Circle3RevealButton = uibutton(app.UIFigure, 'push');
            app.Circle3RevealButton.ButtonPushedFcn = createCallbackFcn(app, @Circle3RevealButtonPushed, true);
            app.Circle3RevealButton.Visible = 'off';
            app.Circle3RevealButton.Text = 'Reveal';
            app.PerpendicularbisectorsLabel = uilabel(app.UIFigure);
            app.PerpendicularbisectorsLabel.Visible = 'off';
            app.PerpendicularbisectorsLabel.Text = 'Perpendicular bisectors:';
            app.PerpendicularbisectorsHideButton = uibutton(app.UIFigure, 'push');
            app.PerpendicularbisectorsHideButton.ButtonPushedFcn = createCallbackFcn(app, @PerpendicularbisectorsHideButtonPushed, true);
            app.PerpendicularbisectorsHideButton.Visible = 'off';
            app.PerpendicularbisectorsHideButton.Text = 'Hide';
            app.PerpendicularbisectorsRevealButton = uibutton(app.UIFigure, 'push');
            app.PerpendicularbisectorsRevealButton.ButtonPushedFcn = createCallbackFcn(app, @PerpendicularbisectorsRevealButtonPushed, true);
            app.PerpendicularbisectorsRevealButton.Visible = 'off';
            app.PerpendicularbisectorsRevealButton.Text = 'Reveal';
            app.InitialuserselectedpointsLabel = uilabel(app.UIFigure);
            app.InitialuserselectedpointsLabel.Visible = 'off';
            app.InitialuserselectedpointsLabel.Text = 'Initial user selected points:';
            app.InitialuserselectedpointsHideButton = uibutton(app.UIFigure, 'push');
            app.InitialuserselectedpointsHideButton.ButtonPushedFcn = createCallbackFcn(app, @InitialuserselectedpointsHideButtonPushed, true);
            app.InitialuserselectedpointsHideButton.Visible = 'off';
            app.InitialuserselectedpointsHideButton.Text = 'Hide';
            app.InitialuserselectedpointsRevealButton = uibutton(app.UIFigure, 'push');
            app.InitialuserselectedpointsRevealButton.ButtonPushedFcn = createCallbackFcn(app, @InitialuserselectedpointsRevealButtonPushed, true);
            app.InitialuserselectedpointsRevealButton.Visible = 'off';
            app.InitialuserselectedpointsRevealButton.Text = 'Reveal';
            app.CalculatedclosestpointsLabel = uilabel(app.UIFigure);
            app.CalculatedclosestpointsLabel.Visible = 'off';
            app.CalculatedclosestpointsLabel.Text = 'Calculated closest points:';
            app.CalculatedclosestpointsHideButton = uibutton(app.UIFigure, 'push');
            app.CalculatedclosestpointsHideButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatedclosestpointsHideButtonPushed, true);
            app.CalculatedclosestpointsHideButton.Visible = 'off';
            app.CalculatedclosestpointsHideButton.Text = 'Hide';
            app.CalculatedclosestpointsRevealButton = uibutton(app.UIFigure, 'push');
            app.CalculatedclosestpointsRevealButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatedclosestpointsRevealButtonPushed, true);
            app.CalculatedclosestpointsRevealButton.Visible = 'off';
            app.CalculatedclosestpointsRevealButton.Text = 'Reveal';
            app.LoadCoordinatesButton = uibutton(app.UIFigure, 'push');
            app.LoadCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoordinatesButtonPushed, true);
            app.LoadCoordinatesButton.Text = 'Load Coordinates';
            app.ModifyElementsButton = uibutton(app.UIFigure, 'push');
            app.ModifyElementsButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyElementsButtonPushed, true);
            app.ModifyElementsButton.Text = 'Modify Elements';
            app.Circle1CentreButton = uibutton(app.UIFigure, 'push');
            app.Circle1CentreButton.ButtonPushedFcn = createCallbackFcn(app, @Circle1CentreButtonPushed, true);
            app.Circle1CentreButton.Visible = 'off';
            app.Circle1CentreButton.Text = 'Circle 1 Centre';
            app.Circle1PointButton = uibutton(app.UIFigure, 'push');
            app.Circle1PointButton.ButtonPushedFcn = createCallbackFcn(app, @Circle1PointButtonPushed, true);
            app.Circle1PointButton.Visible = 'off';
            app.Circle1PointButton.Text = 'Circle 1 Point';
            app.Circle2CentreButton = uibutton(app.UIFigure, 'push');
            app.Circle2CentreButton.ButtonPushedFcn = createCallbackFcn(app, @Circle2CentreButtonPushed, true);
            app.Circle2CentreButton.Visible = 'off';
            app.Circle2CentreButton.Text = 'Circle 2 Centre';
            app.Circle3Point1Button = uibutton(app.UIFigure, 'push');
            app.Circle3Point1Button.ButtonPushedFcn = createCallbackFcn(app, @Circle3Point1ButtonPushed, true);
            app.Circle3Point1Button.Visible = 'off';
            app.Circle3Point1Button.Text = 'Circle 3 Point 1';
            app.Circle3Point2Button = uibutton(app.UIFigure, 'push');
            app.Circle3Point2Button.ButtonPushedFcn = createCallbackFcn(app, @Circle3Point2ButtonPushed, true);
            app.Circle3Point2Button.Visible = 'off';
            app.Circle3Point2Button.Text = 'Circle 3 Point 2';
            app.Circle3Point3Button = uibutton(app.UIFigure, 'push');
            app.Circle3Point3Button.ButtonPushedFcn = createCallbackFcn(app, @Circle3Point3ButtonPushed, true);
            app.Circle3Point3Button.Visible = 'off';
            app.Circle3Point3Button.Text = 'Circle 3 Point 3';
            app.DisableAnimationButton = uibutton(app.UIFigure, 'push');
            app.DisableAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @DisableAnimationButtonPushed, true);
            app.DisableAnimationButton.Text = 'Disable Animation';
            app.SphereRevealButton = uibutton(app.UIFigure, 'push');
            app.SphereRevealButton.ButtonPushedFcn = createCallbackFcn(app, @SphereRevealButtonPushed, true);
            app.SphereRevealButton.Visible = 'off';
            app.SphereRevealButton.Text = 'Reveal';
            app.SphereHideButton = uibutton(app.UIFigure, 'push');
            app.SphereHideButton.ButtonPushedFcn = createCallbackFcn(app, @SphereHideButtonPushed, true);
            app.SphereHideButton.Visible = 'off';
            app.SphereHideButton.Text = 'Hide';
            app.SphereLabel = uilabel(app.UIFigure);
            app.SphereLabel.Visible = 'off';
            app.SphereLabel.Text = 'Sphere:';
            app.BuildSphereButton = uibutton(app.UIFigure, 'push');
            app.BuildSphereButton.ButtonPushedFcn = createCallbackFcn(app, @BuildSphereButtonPushed, true);
            app.BuildSphereButton.Text = 'Build Sphere';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Code_21090174

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end